classdef ACS233_Matlab_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AddinganewuserButton        matlab.ui.control.Button
        ColourselectionButton       matlab.ui.control.Button
        AccessibilitymanagerButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AccessibilitymanagerButton
        function AccessibilitymanagerButtonPushed(app, event)
          
            Textsize={'What text size would you prefer?'};
            [indx,tf]=listdlg("ListString",Textsize);
            Options={'Big','Medium','Small'};
            [indx,tf]=listdlg("ListString",Options);
            
            if strcmp(Options,'Big') 
            fontsize(scale=1.6)
            end
            if strcmp(Options,'Medium') 
            fontsize(scale=1.0)
            end
            if strcmp(Options,'Small') 
            fontsize(scale=0.8)
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
        
        end

        % Button pushed function: ColourselectionButton
        function ColourselectionButtonPushed(app, event)
           R=[1 0 0] %colour red
           G=[0 1 0] %colour green
           B=[0 0 1] %colour blue
           DesiredColour={[1 0 0], [0 1 0], [0 0 1]}
            Colour={'Select the desired colour'}
            msg=sprintf('Red(R),Green(G),Blue(B)')
            [indx,tf] = listdlg('ListString',Colour);
           y=input(DesiredColour) %not sure what to do here tbh
if DesiredColour==R
    Red={'What woud you like to do?'}
    [indx,tf] = listdlg('ListSize',Red);
elseif DesiredColour==G
    Green={'What woud you like to do?'}
    [indx,tf] = listdlg('ListSize',Green);
elseif DesiredColour==B
    Green={'What woud you like to do?'}
    [indx,tf] = listdlg('ListSize',Blue);
else    
    sprintf('Try again')
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.AccessibilitymanagerButton = uibutton(app.UIFigure, 'push');
            app.AccessibilitymanagerButton.ButtonPushedFcn = createCallbackFcn(app, @AccessibilitymanagerButtonPushed, true);
            app.AccessibilitymanagerButton.Text = 'Accessibility manager';
            app.ColourselectionButton = uibutton(app.UIFigure, 'push');
            app.ColourselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ColourselectionButtonPushed, true);
            app.ColourselectionButton.Text = 'Colour selection';
            app.AddinganewuserButton = uibutton(app.UIFigure, 'push');
            app.AddinganewuserButton.Text = 'Adding a new user';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ACS233_Matlab_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end