classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CreateNewSystemButton          matlab.ui.control.Button
        InitialiseSystemTabGroup       matlab.ui.container.TabGroup
        InitialiseSystemTab            matlab.ui.container.Tab
        AttemptsEditField              matlab.ui.control.NumericEditField
        AttemptsEditFieldLabel         matlab.ui.control.Label
        initialPassword                matlab.ui.control.EditField
        NameEditField_5Label_3         matlab.ui.control.Label
        InitialiseSystemButton         matlab.ui.control.Button
        InitialLockLabel               matlab.ui.control.Label
        InitialSensorLabel             matlab.ui.control.Label
        LockPinNumberEditField_2       matlab.ui.control.NumericEditField
        PinNumberLabel_2               matlab.ui.control.Label
        LockNameEditField_2            matlab.ui.control.EditField
        NameEditField_4Label_2         matlab.ui.control.Label
        SensorLogicSwitch_2            matlab.ui.control.Switch
        SensorTypeSwitch_2             matlab.ui.control.Switch
        SensorPinNumberEditField_2     matlab.ui.control.NumericEditField
        PinNumberEditField_3Label_2    matlab.ui.control.Label
        SensorNameEditField_2          matlab.ui.control.EditField
        NameEditField_3Label_2         matlab.ui.control.Label
        InitialAdminName               matlab.ui.control.EditField
        NameEditField_5Label_2         matlab.ui.control.Label
        LoadSystemButton               matlab.ui.control.Button
        FontNameDropDown               matlab.ui.control.DropDown
        FontNameLabel                  matlab.ui.control.Label
        SetPreferencesButton           matlab.ui.control.Button
        FontsizeDropDown               matlab.ui.control.DropDown
        FontsizeDropDownLabel          matlab.ui.control.Label
        BackgroundcolourDropDown       matlab.ui.control.DropDown
        BackgroundcolourDropDownLabel  matlab.ui.control.Label
        FontcolourDropDown             matlab.ui.control.DropDown
        FontcolourDropDownLabel        matlab.ui.control.Label
        SystemArmedLamp                matlab.ui.control.Lamp
        SystemArmedLampLabel           matlab.ui.control.Label
        LOCKEDOUTLabel                 matlab.ui.control.Label
        NameBox                        matlab.ui.control.Label
        LogoutButton                   matlab.ui.control.Button
        SystemTabs                     matlab.ui.container.TabGroup
        SystemManagerTab               matlab.ui.container.Tab
        AbortArmingButton              matlab.ui.control.StateButton
        SaveAndCloseSystemButton       matlab.ui.control.Button
        TestSystemConsoleTextArea      matlab.ui.control.TextArea
        TestSystemConsoleLabel         matlab.ui.control.Label
        ArmSystemButton                matlab.ui.control.Button
        SystemwillarminEditField       matlab.ui.control.NumericEditField
        SystemwillarminEditFieldLabel  matlab.ui.control.Label
        TestSystemButton               matlab.ui.control.Button
        SensorManagerTab               matlab.ui.container.Tab
        UpdateSensorInfoButton         matlab.ui.control.Button
        SensorDataTable                matlab.ui.control.Table
        SensorIndexEditField           matlab.ui.control.NumericEditField
        SensorIndexEditFieldLabel      matlab.ui.control.Label
        SensorConfirmActionButton      matlab.ui.control.Button
        SensorLogicSwitch              matlab.ui.control.Switch
        SensorTypeSwitch               matlab.ui.control.Switch
        SensorPinNumberEditField       matlab.ui.control.NumericEditField
        PinNumberEditField_3Label      matlab.ui.control.Label
        SensorNameEditField            matlab.ui.control.EditField
        NameEditField_3Label           matlab.ui.control.Label
        SensorAddRemoveSwitch          matlab.ui.control.Switch
        LockManagerTab                 matlab.ui.container.Tab
        UpdateLockInfoButton           matlab.ui.control.Button
        LockDataTable                  matlab.ui.control.Table
        LockIndexEditField             matlab.ui.control.NumericEditField
        LockIndexEditFieldLabel        matlab.ui.control.Label
        LockNameEditField              matlab.ui.control.EditField
        NameEditField_4Label           matlab.ui.control.Label
        LockConfirmActionButton        matlab.ui.control.Button
        LockPinNumberEditField         matlab.ui.control.NumericEditField
        PinNumberLabel                 matlab.ui.control.Label
        LockAddRemoveSwitch            matlab.ui.control.Switch
        LoginManagerTab                matlab.ui.container.Tab
        UpdateDelayButton              matlab.ui.control.Button
        AlarmDelayEditField            matlab.ui.control.NumericEditField
        AlarmDelayEditFieldLabel       matlab.ui.control.Label
        MaxAttemptsEditField           matlab.ui.control.NumericEditField
        MaxAttemptsEditFieldLabel      matlab.ui.control.Label
        NewPasswordEditField           matlab.ui.control.EditField
        NewPasswordEditFieldLabel      matlab.ui.control.Label
        UpdateCredentialsButton        matlab.ui.control.Button
        AddRemoveLoginButton           matlab.ui.control.Button
        LoginNameEditField             matlab.ui.control.EditField
        NameEditField_5Label           matlab.ui.control.Label
        LoginAddRemoveSwitch           matlab.ui.control.Switch
        UserAdminSwitch                matlab.ui.control.Switch
        CheckLoginButton               matlab.ui.control.Button
        LoginEditField                 matlab.ui.control.EditField
        LoginEditFieldLabel            matlab.ui.control.Label
    end

   %%% MATLAB VERSION R2023B 
    properties (Access = private)
        arduino
        abortFlag
        abortTime
        users
        newnet
    end
    
    methods (Access = private)
        function value = CheckPassword(app)
            %code for serial communication with arduino
            message=readline(app.arduino)  %see what state the app.arduino is in, is neccessary to know when logging in, logging out, and if armed/disarmed
            if (string(message) == "Check Login")
                password=app.LoginEditField.Value;
                %facial recognition prediction
                userID=Predict(50,'s01Test',app.newnet,length(app.users));
                if userID>0
                    username=app.users(userID);
                else 
                    username="Failed Login Attempt";
                end
                writeline(app.arduino,"1"); %if there is a login write 1 to the app.arduino
                app.communicate("Check Username",username); %then communicate to the app.arduino the username and password (NOTE: the username step will be changed when i add facial fully)
                app.communicate("Check Password",password);
                state = readline(app.arduino);
                %if too many inputs it will wait for the arduino to send
                %something that isnt locked
                if(state == "Locked")
                    app.SystemTabs.Visible = "off";
                    app.LoginEditField.Visible = "off";
                    app.LoginEditFieldLabel.Visible = "off";
                    app.CheckLoginButton.Visible = "off";
                    app.LOCKEDOUTLabel.Visible = "on";
                    message=readline(app.arduino);
                    while(message == "System Locked")
                        message=readline(app.arduino);
                    end
                    app.LoginEditField.Visible = "on";
                    app.LoginEditFieldLabel.Visible = "on";
                    app.CheckLoginButton.Visible = "on";
                    app.LOCKEDOUTLabel.Visible = "off";
                end
                message=readline(app.arduino);
                if (message=="User Logged in")
                    value = 1;
                    app.NameBox.Visible = "on";
                    %show username in box
                    app.NameBox.Text = ['Hello '+username,'You are logged in as a User.'];
                    app.SystemArmedLamp.Color = [0.80,0.80,0.80];
                elseif (message=="Admin Logged in") %Admin pannel
                    value = 2;
                    app.NameBox.Visible = "on";
                    app.NameBox.Text = ['Hello '+username,'You are logged in as an Admin.'];
                    app.SystemArmedLamp.Color = [0.80,0.80,0.80];
                else
                    app.NameBox.Visible = "off";
                    value = 0;
                end
            else
                value = 0;
                disp("invalid app.arduino State"); % FOR DEBUGGING
            end
        end
        
        function ArmSystem(app)
            app.SystemwillarminEditField.Visible = "on";
            app.AbortArmingButton.Visible = "on";
            app.SystemwillarminEditFieldLabel.Visible = "on";
            %Countdown stuff
            startTime = datetime("now");
            currentTime = datetime("now");
            timediff = floor(seconds(currentTime-startTime));
            app.abortFlag=0;
            app.ArmSystemButton.Enable = "off";
            drawnow;
            
            while (timediff < app.abortTime && app.abortFlag == 0)
                %check if the abort button is pressed
                if(app.AbortArmingButton.Value == 1)
                    app.abortFlag = 1;
                end
                countdown = app.abortTime-timediff;
                app.SystemwillarminEditField.Value = countdown;
                currentTime = datetime("now");
                timediff = floor(seconds(currentTime-startTime));
                drawnow;    %drawnow to allow for matlab to update the gui and read if abortd
            end
            %reset things after countdown/abort
            app.ArmSystemButton.Enable = "on";
            app.SystemwillarminEditField.Visible = "off";
            app.AbortArmingButton.Visible = "off";
            app.AbortArmingButton.Value = 0;
            app.SystemwillarminEditFieldLabel.Visible = "off";
            drawnow;
            if (app.abortFlag == 0)
                %make everything invisible as the system has logged out if
                %it hasnt aborted 
                writeline(app.arduino,"A");
                app.SystemTabs.Visible = "off";
                app.LogoutButton.Visible = "off";
                app.NameBox.Visible = "off";
                app.CheckLoginButton.Visible = "on";
                app.LoginEditField.Visible = "on";
                app.LoginEditFieldLabel.Visible = "on";
                app.SystemArmedLamp.Color = [1.00,0.00,0.00];
            end
            
        end

        function TestSystem(app)
            writeline(app.arduino,"T"); %send test command to arduino
            message = string(readline(app.arduino));
            while(message ~= "Test Complete")   
                app.TestSystemConsoleTextArea.Value = message;
                message = readline(app.arduino);
            end
        end
        
        function Logout(app)
            %make everything invisible to user
            app.SystemTabs.Visible = "off";
            app.LogoutButton.Visible = "off";
            app.CheckLoginButton.Visible = "on";
            app.LoginEditField.Visible = "on";
            app.LoginEditFieldLabel.Visible = "on";
            app.NameBox.Visible = "off";
            writeline(app.arduino,"L"); %send logout command to arduino
            readline(app.arduino)       %readline to check if correct state and clear the line
        end
        
        %waits for the arduino to send the prompt then sends the
        %corresponding info
        function communicate(app,prompt,info)   %communicates w the app.arduino in a way that avoids errors
            while(1)
                if (string(readline(app.arduino))==prompt)
                    writeline(app.arduino,info);
                    break
                end
            end
        end
        
        %clears all the photo files 
        function clearFaceID(app)
           for i=0:12
               str = ['s0',int2str(i)];
               delete(['photos\',str,'\*.jpg']);
               delete(['croppedfaces\',str,'\*.jpg'])
           end
        end

        function removeUserFromFacialID(app,userNo,numberOfUsers)   %removes users from the directories
           str = ['s0',int2str(userNo)];
           delete(['photos\',str,'\*.jpg']);
           delete(['croppedfaces\',str,'\*.jpg']);
           for i=userNo:numberOfUsers %did the logic off vibes so could be wrong lmao
               str = ['s0',int2str(i)];
               str1 = ['s0',int2str(i+1)];
               movefile(['photos\',str1,'\*.jpg'],['photos\',str]) %source,destination shifts them one folder backwards
               movefile(['croppedfaces\',str1,'\*.jpg'],['photos\',str]) %source,destination shifts them one folder backwards
           end
        end

        function UpdateUser(app)
            username = app.LoginNameEditField.Value;
            %sanitise inputs as cant be error message or include commas
            if (username == "Failed Login Attempt" || contains(username,",") == 1)
                app.LoginNameEditField.Value = "";
                app.LoginNameEditField.Placeholder = "invalid Name";
                return
            else
                app.LoginNameEditField.Placeholder = "";
            end

            app.AddRemoveLoginButton.Enable = "off";
            writeline(app.arduino,"U");     %sends update commmand
            AorR= app.LoginAddRemoveSwitch.Value;
            %CONVERT TO A or R
            if (AorR == "Add")
                AorR = "A";
            else
                AorR = "R";
            end
            %Communication sequence with arduino to send correct info
            app.communicate("Add or Remove",AorR);
            
            app.communicate("Get Username",username); %communicates the neccessary information to identify what is being selected

            if AorR=="A"
                app.users(end+1)=username; %adds the new username to the array
                userCount=length(app.users);
                app.newnet=SimpleFaceRecognition(userCount,userCount); %adds a new face at the registered value
                isAdmin = app.UserAdminSwitch.Value;
                %CONVERTS ADMIN SWITCH TO 1 or 0
                if (isAdmin == "Admin")
                    isAdmin = "1";
                else
                    isAdmin = "0";
                end
                app.communicate("Get Admin",isAdmin);
            else
                index=find(app.users==username); %finds the index
                if index %if in array
                    app.users(index)=[]; %removes the username
                    app.removeUserFromFacialID(index,length(app.users)); %removes the user
                    app.newnet=SimpleFaceRecognition(0,0); %reruns the facial recognition with the change
                end
            end
            disp(readline(app.arduino));    %display success/error message to user for adding/removing, after the app.arduino has done the computation
            readline(app.arduino);
            app.AddRemoveLoginButton.Enable = "on";
        end
        
        function AddOrRemoveSensor(app)
            writeline(app.arduino,"D");     %write edit device command to arduino
            AorR= app.SensorAddRemoveSwitch.Value;
            %CONVERT TO A or R
            if (AorR == "Add")
                AorR = "A";
                %communicate info to matlab
                app.communicate("Add or remove",AorR);
                app.communicate("Sensor or Lock","Sensor");
                app.communicate("Pin Number",string(app.SensorPinNumberEditField.Value));
                app.communicate("Name",app.SensorNameEditField.Value);
                app.communicate("Logic",string(app.SensorLogicSwitch.Value));
                app.communicate("Standard or Pullup",string(app.SensorTypeSwitch.Value));
            else
                AorR = "R";
                %communicate info to matlab
                app.communicate("Add or remove",AorR);
                app.communicate("Sensor or Lock","S");
                app.communicate("Sensor Number",string(app.SensorIndexEditField.Value));
            end
            readline(app.arduino)
        end
        
        function AddOrRemoveLock(app)
            writeline(app.arduino,"D");     %write edit device command to arduino
            AorR= app.LockAddRemoveSwitch.Value;
            %CONVERT TO A or R
            if (AorR == "Add")
                AorR = "A";
                %communicate info to matlab
                app.communicate("Add or remove",AorR);
                app.communicate("Sensor or Lock","Lock");
                app.communicate("Pin Number",string(app.LockPinNumberEditField.Value));
                app.communicate("Name",app.LockNameEditField.Value);
            else
                AorR = "R";
                %communicate info to matlab
                app.communicate("Add or remove",AorR);
                app.communicate("Sensor or Lock","L");
                app.communicate("Lock Number",string(app.LockIndexEditField.Value));
            end
            readline(app.arduino)
        end
        
        %reads from serial until an end message is detected
        function arr=readUntilStop(app,endMessage,indexFlag)
            arr = string([]);
            message=readline(app.arduino);
            i = 0;
            while (message~=endMessage)
                if(indexFlag)
                    arr(i+1,1)=i;
                    arr(i+1,2)=message;
                else
                    arr(i+1)=message;
                end
                i=i+1;
                message=readline(app.arduino)
            end
        end
        
        %converts the colours into the RGB values
        function colour = ConvertColour(app,colourOption)

            switch colourOption
                case 'Red'
                    colour=[1 0 0];
                case 'Green'
                    colour=[0 1 0];
                case 'Blue'
                    colour=[0 0 1];
                case 'Black'
                    colour=[0 0 0];
                case 'White'
                    colour=[1 1 1];
                case 'Cyan'
                    colour=[0.34,0.98,0.94];
                case 'Yellow'
                    colour=[0.97,0.97,0.61];
                case 'Grey'
                    colour=[0.96,0.96,0.96];
                case 'Pink'
                    colour=[0.97,0.68,1.00];
                otherwise   
                    colour=[0 0 0];
            end
        end
        
        %opens a file and sends its content to the arduino line by line
        function SendFile(app, FileName, EndPrompt)
            FileID = fopen(FileName);
            line = fgetl(FileID);
            while ischar(line)
                app.communicate("NEXT",string(line));
                line = fgetl(FileID);
            end
            app.communicate("NEXT",EndPrompt);
            fclose(FileID);
        end
        
        %saves info to a file given its start and end prompt from arduino
        function saveInformation(app,prompt,endPrompt,filename) %saves a set of information to a file.
            %waits for start promtp
            while(string(readline(app.arduino))~=prompt)
            end
            %sends command to start
            writeline(app.arduino,"1");
            arr=app.readUntilStop(endPrompt,0);
            fid=fopen(filename,"w");
            if fid == -1
                error('Failed to open file for writing.');
            end
            app.saveToFile(fid,arr);
            fclose(fid);
        end
         
        %saves array to a file
        function saveToFile(app,fid,array) %saves an array to a file
            for i=1:length(array)
                fprintf(fid, '%s\n', array(i));
            end
        end
        
        function SaveSystem(app) %saves the system
            %saves neural network
            newnet = app.newnet;
            save("newnet","newnet");
            %communicates with arduino to get the information and save it
            %to file in matlab
            app.saveInformation("Credentials Start", "Credentials End","credentials.txt");
            writeline(app.arduino,"1");
            app.saveInformation("User List Start", "User List End","users.txt");
            app.saveInformation("Sensor List Start", "Sensor List End","sensors.txt");
            app.saveInformation("Lock List Start", "Lock List End","locks.txt");
        end
        
        function LoadSystem(app)
            %load the neural Network
            load('newnet.mat','newnet');
            app.newnet = newnet;

            app.communicate("Setup Option", "LOAD")
            %get the credentials and send them
            FileID = fopen('credentials.txt','r');
            password = fgetl(FileID);
            attempts = fgetl(FileID);
            fclose(FileID);
            %send the data to to the arduino to load it 
            app.communicate("New Password",password);
            app.communicate("Attempt Change",attempts);
            app.SendFile('users.txt', "Users End");
            app.SendFile('sensors.txt', "Sensors End");
            app.SendFile('locks.txt', "Locks End");

            %get the users in the array
            FileID = fopen('users.txt');
            line = fgetl(FileID);
            while ischar(line)
                line = line(1:end-2)
                app.users(end+1) = line;
                line = fgetl(FileID);
            end
            fclose(FileID);

        end

        function InitialiseSystem(app)
            %send passwords, attempts ,user first , lock , sensor, 
            app.communicate("Setup Option", "Create");
            %send credentials
            app.communicate("New Password",app.initialPassword.Value);
            app.communicate("Attempt Change",string(app.AttemptsEditField.Value));
            %Send User Info
            app.communicate("Add or Remove","A");
            app.users(end+1)=string(app.InitialAdminName.Value);
            app.communicate("Get Username",app.InitialAdminName.Value);
            app.communicate("Get Admin","1");
            readline(app.arduino);
            %Send Lock
            app.communicate("Add or remove","A");
            app.communicate("Sensor or Lock","Lock");
            app.communicate("Pin Number",string(app.LockPinNumberEditField_2.Value));
            app.communicate("Name",app.LockNameEditField_2.Value);
            %Send Sensor
            app.communicate("Add or remove","A");
            app.communicate("Sensor or Lock","Sensor");
            app.communicate("Pin Number",string(app.SensorPinNumberEditField_2.Value));
            app.communicate("Name",app.SensorNameEditField_2.Value);
            app.communicate("Logic",string(app.SensorLogicSwitch_2.Value));
            app.communicate("Standard or Pullup",string(app.SensorTypeSwitch_2.Value));
            %train Network
            app.clearFaceID();
            app.newnet=SimpleFaceRecognition(1,1); %adds a new face at the registered value
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.users=string([]);
            app.abortTime = 10;
            app.SystemArmedLamp.Color = [0.80,0.80,0.80];

            clear app.arduino;
            app.arduino = serialport("COM9",9600);      %CHANGE TO YOUR COM PORT
            configureTerminator(app.arduino,"CR/LF");
        end

        % Button pushed function: CheckLoginButton
        function CheckLoginButtonPushed(app, event)
            %disable button to prevent spam
            app.CheckLoginButton.Enable = "off";
            app.LoginEditField.Enable = "off";
            app.LoginEditFieldLabel.Enable = "off";
            if (isempty(app.LoginEditField.Value))
                value = 0;
            else
                value = app.CheckPassword();
            end
            if (value == 2) %put check if login correct here
                app.SystemTabs.Visible = "on";
                app.SensorManagerTab.Parent = app.SystemTabs; %this code ensures tabs are visible when needed 
                app.LockManagerTab.Parent = app.SystemTabs;
                app.LoginManagerTab.Parent = app.SystemTabs;
                app.LogoutButton.Visible = "on";
                app.CheckLoginButton.Visible = "off";
                app.LoginEditField.Visible = "off";
                app.LoginEditFieldLabel.Visible = "off";
            elseif(value == 1)
                app.SystemTabs.Visible = "on";
                app.SensorManagerTab.Parent = [];   %this code effectively hides the tabs as there is not currently a visibility toggle per tabs
                app.LockManagerTab.Parent = [];
                app.LoginManagerTab.Parent = [];
                app.LogoutButton.Visible = "on";
                app.CheckLoginButton.Visible = "off";
                app.LoginEditField.Visible = "off";
                app.LoginEditFieldLabel.Visible = "off";
            end 
            app.LoginEditField.Value = '';
            app.CheckLoginButton.Enable = "on";
            app.LoginEditField.Enable = "on";
            app.LoginEditFieldLabel.Enable = "on";
        end

        % Button pushed function: TestSystemButton
        function TestSystemButtonPushed(app, event)
            %disables button then tests system then renales button
            app.TestSystemButton.Enable = "off";
            app.TestSystem();
            app.TestSystemConsoleTextArea.Value = "";
            app.TestSystemButton.Enable = "on";
        end

        % Button pushed function: ArmSystemButton
        function ArmSystemSwitchValueChanged(app, event)
            app.ArmSystem();
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            app.Logout();
            disp("logout")
        end

        % Value changed function: SensorAddRemoveSwitch
        function SensorAddRemoveSwitchValueChanged(app, event)
            %changes what is visible to the user depending on weather it is
            %adding or removing a sensor
            value = app.SensorAddRemoveSwitch.Value;
            if (value == "Add")
                app.SensorLogicSwitch.Visible = "on";
                app.SensorTypeSwitch.Visible = "on";
                app.SensorPinNumberEditField.Visible = "on";
                app.PinNumberEditField_3Label.Visible = "on";
                app.SensorNameEditField.Visible = "on";
                app.NameEditField_3Label.Visible = "on";
                app.SensorIndexEditField.Visible = "off";
                app.SensorIndexEditFieldLabel.Visible = "off";
            else
                app.SensorLogicSwitch.Visible = "off";
                app.SensorTypeSwitch.Visible = "off";
                app.SensorPinNumberEditField.Visible = "off";
                app.PinNumberEditField_3Label.Visible = "off";
                app.SensorNameEditField.Visible = "off";
                app.NameEditField_3Label.Visible = "off";
                app.SensorIndexEditField.Visible = "on";
                app.SensorIndexEditFieldLabel.Visible = "on";
            end
        end

        % Value changed function: LoginAddRemoveSwitch
        function LoginAddRemoveSwitchValueChanged(app, event)
            value = app.LoginAddRemoveSwitch.Value;
            % if the value is Add then make name  and user admin switch
            % visible, if the value is Remove than make only the name
            % visible
            if (value == "Add")
                app.UserAdminSwitch.Visible = "on";
            else
                app.UserAdminSwitch.Visible = "off";
            end

        end

        % Button pushed function: AddRemoveLoginButton
        function AddRemoveLoginButtonPushed(app, event)
            app.UpdateUser();
        end

        % Button pushed function: SensorConfirmActionButton
        function SensorConfirmActionButtonPushed(app, event)
            app.SensorConfirmActionButton.Enable = "off";  
            app.AddOrRemoveSensor();
            app.SensorConfirmActionButton.Enable = "on";  
        end

        % Button pushed function: LockConfirmActionButton
        function LockConfirmActionButtonPushed(app, event)
            app.AddOrRemoveLock();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Value changed function: LockAddRemoveSwitch
        function LockAddRemoveSwitchValueChanged(app, event)
            %changes whats visible depending on add or remove
            value = app.LockAddRemoveSwitch.Value;
            if (value == "Add")
                app.LockNameEditField.Visible = "on";
                app.NameEditField_4Label.Visible = "on";
                app.LockPinNumberEditField.Visible = "on";
                app.LockIndexEditField.Visible = "off";
                app.LockIndexEditFieldLabel.Visible = "off";
            else
                app.LockNameEditField.Visible = "off";
                app.NameEditField_4Label.Visible = "off";
                app.LockPinNumberEditField.Visible = "off";
                app.LockIndexEditField.Visible = "on";
                app.LockIndexEditFieldLabel.Visible = "on";
            end
        end

        % Button pushed function: UpdateSensorInfoButton
        function UpdateSensorInfoButtonPushed(app, event)
            %reads in sensor info from arduino and displays it on the
            %screen with indexes
            readline(app.arduino)
            writeline(app.arduino,"SE");
            temp = readline(app.arduino);
            array = [];
            if (temp=="Sensor List Start")
                writeline(app.arduino,"1");
                array = app.readUntilStop("Sensor List End",1);
            end
            app.SensorDataTable.Data = array;
            readline(app.arduino)
        end

        % Button pushed function: UpdateLockInfoButton
        function UpdateLockInfoButtonPushed(app, event)
            %reads in lock info from arduino and displays it on the
            %screen with indexes
            readline(app.arduino)
            writeline(app.arduino,"LO");
            temp = readline(app.arduino);
            array = [];
            if (temp=="Lock List Start")
                writeline(app.arduino,"1");
                array = app.readUntilStop("Lock List End",1);
            end
            app.LockDataTable.Data = array;
            readline(app.arduino)
        end

        % Button pushed function: UpdateCredentialsButton
        function UpdateCredentialsButtonPushed(app, event)
            %Communication sequence with arduino to update credentials 
            newpassword = app.NewPasswordEditField.Value
            if (contains(newpassword,",") == 1)
                app.NewPasswordEditField.Value = "";
                app.NewPasswordEditField.Placeholder = "invalid Password";
                return;
            else
                app.NewPasswordEditField.Placeholder = "";
            end
            app.UpdateCredentialsButton.Enable = "off";
            writeline(app.arduino, "C");
            if (app.NewPasswordEditField.Value == "")
                newpassword = "0";
            end
            app.communicate("New Password", newpassword);

            app.communicate("Attempt Change", string(app.MaxAttemptsEditField.Value));
            app.UpdateCredentialsButton.Enable = "on";
            readline(app.arduino);
        end

        % Value changed function: FontcolourDropDown
        function FontcolourDropDownValueChanged(app, event)
            value = app.FontcolourDropDown.Value;
            colour = app.ConvertColour(value);
            app.SetPreferencesButton.FontColor = colour;
        end

        % Value changed function: BackgroundcolourDropDown
        function BackgroundcolourDropDownValueChanged(app, event)
            value = app.BackgroundcolourDropDown.Value;
            app.SetPreferencesButton.BackgroundColor = app.ConvertColour(value);
        end

        % Value changed function: FontsizeDropDown
        function FontsizeDropDownValueChanged(app, event)
            value = app.FontsizeDropDown.Value;
            
            switch value
                case '12'
                    app.SetPreferencesButton.FontSize=12;
                case '16'
                    app.SetPreferencesButton.FontSize=16;
                case '20'
                    app.SetPreferencesButton.FontSize=20;
            end  
        end

        % Button pushed function: SetPreferencesButton
        function SetPreferencesButtonPushed(app, event)
            backgroundcolour=app.ConvertColour(app.BackgroundcolourDropDown.Value);
            fontcolour=app.ConvertColour(app.FontcolourDropDown.Value);
            fontsize=str2num(app.FontsizeDropDown.Value);
            fontName=app.FontNameDropDown.Value;
            
            %these find all elements with the given font properties and
            %sets them to the new value
            set(findall(groot, '-property', 'BackgroundColor'), 'BackgroundColor', backgroundcolour);
            set(findall(groot, '-property', 'FontColor'), 'FontColor', fontcolour);
            set(findall(groot, '-property', 'FontSize'), 'FontSize', fontsize);
            set(findall(groot, '-property', 'FontName'), 'FontName', fontName);
        end

        % Value changed function: FontNameDropDown
        function FontNameDropDownValueChanged(app, event)
            value = app.FontNameDropDown.Value;
            app.SetPreferencesButton.FontName = value;
        end

        % Button pushed function: UpdateDelayButton
        function UpdateDelayButtonPushed(app, event)
            app.UpdateDelayButton.Enable = "off";
            writeline(app.arduino, "UT");
            app.communicate("Get Delay", string(app.AlarmDelayEditField.Value))
            app.UpdateDelayButton.Enable = "on";
            readline(app.arduino);
        end

        % Button pushed function: LoadSystemButton
        function LoadSystemButtonPushed(app, event)
            app.CreateNewSystemButton.Visible = "off";
            app.LoadSystemButton.Visible = "off";
            app.LoadSystem();
            app.CheckLoginButton.Visible = "on";
            app.LoginEditField.Visible = "on";
            app.LoginEditFieldLabel.Visible = "on";
        end

        % Button pushed function: CreateNewSystemButton
        function CreateNewSystemButtonPushed(app, event)
            app.InitialiseSystemTabGroup.Visible = "on";
            app.CreateNewSystemButton.Visible = "off";
            app.LoadSystemButton.Visible = "off";
        end

        % Button pushed function: InitialiseSystemButton
        function InitialiseSystemButtonPushed(app, event)
            app.InitialiseSystemTabGroup.Visible = "off";
            app.InitialiseSystem();
            app.CheckLoginButton.Visible = "on";
            app.LoginEditField.Visible = "on";
            app.LoginEditFieldLabel.Visible = "on";
        end

        % Button pushed function: SaveAndCloseSystemButton
        function SaveAndCloseSystemButtonPushed(app, event)
            app.SaveAndCloseSystemButton.Enable = "off";
            writeline(app.arduino, "S");
            app.SaveSystem();
            readline(app.arduino)
            app.SaveAndCloseSystemButton.Enable = "on";
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoginEditFieldLabel = uilabel(app.UIFigure);
            app.LoginEditFieldLabel.Visible = 'off';
            app.LoginEditFieldLabel.Text = 'Login';
            app.LoginEditField = uieditfield(app.UIFigure, 'text');
            app.LoginEditField.Visible = 'off';
            app.CheckLoginButton = uibutton(app.UIFigure, 'push');
            app.CheckLoginButton.ButtonPushedFcn = createCallbackFcn(app, @CheckLoginButtonPushed, true);
            app.CheckLoginButton.Visible = 'off';
            app.CheckLoginButton.Text = 'Check Login';
            app.SystemTabs = uitabgroup(app.UIFigure);
            app.SystemTabs.Visible = 'off';
            app.SystemManagerTab = uitab(app.SystemTabs);
            app.SystemManagerTab.Title = 'System Manager';
            app.TestSystemButton = uibutton(app.SystemManagerTab, 'push');
            app.TestSystemButton.ButtonPushedFcn = createCallbackFcn(app, @TestSystemButtonPushed, true);
            app.TestSystemButton.Text = 'Test System';
            app.SystemwillarminEditFieldLabel = uilabel(app.SystemManagerTab);
            app.SystemwillarminEditFieldLabel.Visible = 'off';
            app.SystemwillarminEditFieldLabel.Text = 'System will arm in';
            app.SystemwillarminEditField = uieditfield(app.SystemManagerTab, 'numeric');
            app.SystemwillarminEditField.Editable = 'off';
            app.SystemwillarminEditField.Visible = 'off';
            app.ArmSystemButton = uibutton(app.SystemManagerTab, 'push');
            app.ArmSystemButton.ButtonPushedFcn = createCallbackFcn(app, @ArmSystemSwitchValueChanged, true);
            app.ArmSystemButton.Text = 'ArmSystem';
            app.TestSystemConsoleLabel = uilabel(app.SystemManagerTab);
            app.TestSystemConsoleLabel.Text = 'Test System Console';
            app.TestSystemConsoleTextArea = uitextarea(app.SystemManagerTab);
            app.SaveAndCloseSystemButton = uibutton(app.SystemManagerTab, 'push');
            app.SaveAndCloseSystemButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAndCloseSystemButtonPushed, true);
            app.SaveAndCloseSystemButton.Text = 'SaveAndCloseSystem';
            app.AbortArmingButton = uibutton(app.SystemManagerTab, 'state');
            app.AbortArmingButton.Visible = 'off';
            app.AbortArmingButton.Text = 'AbortArming';
            app.SensorManagerTab = uitab(app.SystemTabs);
            app.SensorManagerTab.Title = 'Sensor Manager';
            app.SensorAddRemoveSwitch = uiswitch(app.SensorManagerTab, 'slider');
            app.SensorAddRemoveSwitch.Items = {'Add', 'Remove'};
            app.SensorAddRemoveSwitch.ValueChangedFcn = createCallbackFcn(app, @SensorAddRemoveSwitchValueChanged, true);
            app.SensorAddRemoveSwitch.Value = 'Add';
            app.NameEditField_3Label = uilabel(app.SensorManagerTab);
            app.NameEditField_3Label.Text = 'Name';
            app.SensorNameEditField = uieditfield(app.SensorManagerTab, 'text');
            app.PinNumberEditField_3Label = uilabel(app.SensorManagerTab);
            app.PinNumberEditField_3Label.Text = 'PinNumber';
            app.SensorPinNumberEditField = uieditfield(app.SensorManagerTab, 'numeric');
            app.SensorPinNumberEditField.Limits = [0 Inf];
            app.SensorTypeSwitch = uiswitch(app.SensorManagerTab, 'slider');
            app.SensorTypeSwitch.Items = {'Input', 'Input Pullup'};
            app.SensorTypeSwitch.ItemsData = [1 0];
            app.SensorTypeSwitch.Value = 1;
            app.SensorLogicSwitch = uiswitch(app.SensorManagerTab, 'slider');
            app.SensorLogicSwitch.Items = {'Positive Logic', 'Negative Logic'};
            app.SensorLogicSwitch.ItemsData = [0 1];
            app.SensorLogicSwitch.Value = 1;
            app.SensorConfirmActionButton = uibutton(app.SensorManagerTab, 'push');
            app.SensorConfirmActionButton.ButtonPushedFcn = createCallbackFcn(app, @SensorConfirmActionButtonPushed, true);
            app.SensorConfirmActionButton.Text = 'Confirm Action';
            app.SensorIndexEditFieldLabel = uilabel(app.SensorManagerTab);
            app.SensorIndexEditFieldLabel.Visible = 'off';
            app.SensorIndexEditFieldLabel.Text = 'SensorIndex';
            app.SensorIndexEditField = uieditfield(app.SensorManagerTab, 'numeric');
            app.SensorIndexEditField.Limits = [0 Inf];
            app.SensorIndexEditField.Visible = 'off';
            app.SensorDataTable = uitable(app.SensorManagerTab);
            app.SensorDataTable.ColumnName = {'Index'; 'Sensor'};
            app.SensorDataTable.RowName = {};
            app.UpdateSensorInfoButton = uibutton(app.SensorManagerTab, 'push');
            app.UpdateSensorInfoButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSensorInfoButtonPushed, true);
            app.UpdateSensorInfoButton.Text = 'UpdateSensorInfo';
            app.LockManagerTab = uitab(app.SystemTabs);
            app.LockManagerTab.Title = 'Lock Manager';
            app.LockAddRemoveSwitch = uiswitch(app.LockManagerTab, 'slider');
            app.LockAddRemoveSwitch.Items = {'Add', 'Remove'};
            app.LockAddRemoveSwitch.ValueChangedFcn = createCallbackFcn(app, @LockAddRemoveSwitchValueChanged, true);
            app.LockAddRemoveSwitch.Value = 'Add';
            app.PinNumberLabel = uilabel(app.LockManagerTab);
            app.PinNumberLabel.Text = 'PinNumber';
            app.LockPinNumberEditField = uieditfield(app.LockManagerTab, 'numeric');
            app.LockPinNumberEditField.Limits = [0 Inf];
            app.LockConfirmActionButton = uibutton(app.LockManagerTab, 'push');
            app.LockConfirmActionButton.ButtonPushedFcn = createCallbackFcn(app, @LockConfirmActionButtonPushed, true);
            app.LockConfirmActionButton.Text = 'Confirm Action';
            app.NameEditField_4Label = uilabel(app.LockManagerTab);
            app.NameEditField_4Label.Text = 'Name';
            app.LockNameEditField = uieditfield(app.LockManagerTab, 'text');
            app.LockIndexEditFieldLabel = uilabel(app.LockManagerTab);
            app.LockIndexEditFieldLabel.Visible = 'off';
            app.LockIndexEditFieldLabel.Text = 'LockIndex';
            app.LockIndexEditField = uieditfield(app.LockManagerTab, 'numeric');
            app.LockIndexEditField.Limits = [0 Inf];
            app.LockIndexEditField.Visible = 'off';
            app.LockDataTable = uitable(app.LockManagerTab);
            app.LockDataTable.ColumnName = {'Index'; 'Lock'};
            app.LockDataTable.RowName = {};
            app.UpdateLockInfoButton = uibutton(app.LockManagerTab, 'push');
            app.UpdateLockInfoButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateLockInfoButtonPushed, true);
            app.UpdateLockInfoButton.Text = 'UpdateLockInfo';
            app.LoginManagerTab = uitab(app.SystemTabs);
            app.LoginManagerTab.Title = 'Login Manager';
            app.UserAdminSwitch = uiswitch(app.LoginManagerTab, 'slider');
            app.UserAdminSwitch.Items = {'User', 'Admin'};
            app.UserAdminSwitch.Value = 'User';
            app.LoginAddRemoveSwitch = uiswitch(app.LoginManagerTab, 'slider');
            app.LoginAddRemoveSwitch.Items = {'Add', 'Remove'};
            app.LoginAddRemoveSwitch.ValueChangedFcn = createCallbackFcn(app, @LoginAddRemoveSwitchValueChanged, true);
            app.LoginAddRemoveSwitch.Value = 'Add';
            app.NameEditField_5Label = uilabel(app.LoginManagerTab);
            app.NameEditField_5Label.Text = 'Name';
            app.LoginNameEditField = uieditfield(app.LoginManagerTab, 'text');
            app.AddRemoveLoginButton = uibutton(app.LoginManagerTab, 'push');
            app.AddRemoveLoginButton.ButtonPushedFcn = createCallbackFcn(app, @AddRemoveLoginButtonPushed, true);
            app.AddRemoveLoginButton.Text = 'Add/Remove Login';
            app.UpdateCredentialsButton = uibutton(app.LoginManagerTab, 'push');
            app.UpdateCredentialsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCredentialsButtonPushed, true);
            app.UpdateCredentialsButton.Text = 'UpdateCredentials';
            app.NewPasswordEditFieldLabel = uilabel(app.LoginManagerTab);
            app.NewPasswordEditFieldLabel.Text = 'NewPassword';
            app.NewPasswordEditField = uieditfield(app.LoginManagerTab, 'text');
            app.MaxAttemptsEditFieldLabel = uilabel(app.LoginManagerTab);
            app.MaxAttemptsEditFieldLabel.Text = 'Max Attempts';
            app.MaxAttemptsEditField = uieditfield(app.LoginManagerTab, 'numeric');
            app.MaxAttemptsEditField.Value = 3;
            app.AlarmDelayEditFieldLabel = uilabel(app.LoginManagerTab);
            app.AlarmDelayEditFieldLabel.Text = 'Alarm Delay';
            app.AlarmDelayEditField = uieditfield(app.LoginManagerTab, 'numeric');
            app.AlarmDelayEditField.Limits = [0 Inf];
            app.AlarmDelayEditField.Value = 20;
            app.UpdateDelayButton = uibutton(app.LoginManagerTab, 'push');
            app.UpdateDelayButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDelayButtonPushed, true);
            app.UpdateDelayButton.Text = 'Update Delay';
            app.LogoutButton = uibutton(app.UIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Visible = 'off';
            app.LogoutButton.Text = 'Logout';
            app.NameBox = uilabel(app.UIFigure);
            app.NameBox.Text = '';
            app.LOCKEDOUTLabel = uilabel(app.UIFigure);
            app.LOCKEDOUTLabel.Visible = 'off';
            app.LOCKEDOUTLabel.Text = 'LOCKED OUT';
            app.SystemArmedLampLabel = uilabel(app.UIFigure);
            app.SystemArmedLampLabel.Text = 'System Armed';
            app.SystemArmedLamp = uilamp(app.UIFigure);
            app.FontcolourDropDownLabel = uilabel(app.UIFigure);
            app.FontcolourDropDownLabel.Text = 'Font colour';
            app.FontcolourDropDown = uidropdown(app.UIFigure);
            app.FontcolourDropDown.Items = {'Red', 'Green', 'Blue', 'Black', 'White', 'Cyan', 'Yellow', 'Grey', 'Pink'};
            app.FontcolourDropDown.ValueChangedFcn = createCallbackFcn(app, @FontcolourDropDownValueChanged, true);
            app.FontcolourDropDown.Value = 'Black';
            app.BackgroundcolourDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundcolourDropDownLabel.Text = 'Background colour';
            app.BackgroundcolourDropDown = uidropdown(app.UIFigure);
            app.BackgroundcolourDropDown.Items = {'Red', 'Green', 'Blue', 'Black', 'White', 'Cyan', 'Yellow', 'Grey', 'Pink'};
            app.BackgroundcolourDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundcolourDropDownValueChanged, true);
            app.BackgroundcolourDropDown.Value = 'Grey';
            app.FontsizeDropDownLabel = uilabel(app.UIFigure);
            app.FontsizeDropDownLabel.Text = 'Font size';
            app.FontsizeDropDown = uidropdown(app.UIFigure);
            app.FontsizeDropDown.Items = {'12', '14', '16'};
            app.FontsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @FontsizeDropDownValueChanged, true);
            app.FontsizeDropDown.Value = '12';
            app.SetPreferencesButton = uibutton(app.UIFigure, 'push');
            app.SetPreferencesButton.ButtonPushedFcn = createCallbackFcn(app, @SetPreferencesButtonPushed, true);
            app.SetPreferencesButton.Text = 'Set Preferences';
            app.FontNameLabel = uilabel(app.UIFigure);
            app.FontNameLabel.Text = 'Font Name';
            app.FontNameDropDown = uidropdown(app.UIFigure);
            app.FontNameDropDown.Items = {'Helvetica', 'Arial', 'Comic Sans MS', 'Verdana', 'Century Gothic'};
            app.FontNameDropDown.ValueChangedFcn = createCallbackFcn(app, @FontNameDropDownValueChanged, true);
            app.FontNameDropDown.Value = 'Helvetica';
            app.LoadSystemButton = uibutton(app.UIFigure, 'push');
            app.LoadSystemButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSystemButtonPushed, true);
            app.LoadSystemButton.Text = 'LoadSystem';
            app.InitialiseSystemTabGroup = uitabgroup(app.UIFigure);
            app.InitialiseSystemTabGroup.Visible = 'off';
            app.InitialiseSystemTab = uitab(app.InitialiseSystemTabGroup);
            app.InitialiseSystemTab.Title = 'Initialise System';
            app.NameEditField_5Label_2 = uilabel(app.InitialiseSystemTab);
            app.NameEditField_5Label_2.Text = 'Initial Admin Name';
            app.InitialAdminName = uieditfield(app.InitialiseSystemTab, 'text');
            app.NameEditField_3Label_2 = uilabel(app.InitialiseSystemTab);
            app.NameEditField_3Label_2.Text = 'SensorName';
            app.SensorNameEditField_2 = uieditfield(app.InitialiseSystemTab, 'text');
            app.PinNumberEditField_3Label_2 = uilabel(app.InitialiseSystemTab);
            app.PinNumberEditField_3Label_2.Text = 'SensorPinNumber';
            app.SensorPinNumberEditField_2 = uieditfield(app.InitialiseSystemTab, 'numeric');
            app.SensorPinNumberEditField_2.Limits = [0 Inf];
            app.SensorTypeSwitch_2 = uiswitch(app.InitialiseSystemTab, 'slider');
            app.SensorTypeSwitch_2.Items = {'Input', 'Input Pullup'};
            app.SensorTypeSwitch_2.ItemsData = [1 0];
            app.SensorTypeSwitch_2.Value = 1;
            app.SensorLogicSwitch_2 = uiswitch(app.InitialiseSystemTab, 'slider');
            app.SensorLogicSwitch_2.Items = {'Positive Logic', 'Negative Logic'};
            app.SensorLogicSwitch_2.ItemsData = [0 1];
            app.SensorLogicSwitch_2.Value = 0;
            app.NameEditField_4Label_2 = uilabel(app.InitialiseSystemTab);
            app.NameEditField_4Label_2.Text = 'LockName';
            app.LockNameEditField_2 = uieditfield(app.InitialiseSystemTab, 'text');
            app.PinNumberLabel_2 = uilabel(app.InitialiseSystemTab);
            app.PinNumberLabel_2.Text = 'LockPinNumber';
            app.LockPinNumberEditField_2 = uieditfield(app.InitialiseSystemTab, 'numeric');
            app.LockPinNumberEditField_2.Limits = [0 Inf];
            app.InitialSensorLabel = uilabel(app.InitialiseSystemTab);
            app.InitialSensorLabel.Text = 'Initial Sensor';
            app.InitialLockLabel = uilabel(app.InitialiseSystemTab);
            app.InitialLockLabel.Text = 'Initial Lock';
            app.InitialiseSystemButton = uibutton(app.InitialiseSystemTab, 'push');
            app.InitialiseSystemButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseSystemButtonPushed, true);
            app.InitialiseSystemButton.Text = 'Initialise System';
            app.NameEditField_5Label_3 = uilabel(app.InitialiseSystemTab);
            app.NameEditField_5Label_3.Text = 'Password';
            app.initialPassword = uieditfield(app.InitialiseSystemTab, 'text');
            app.AttemptsEditFieldLabel = uilabel(app.InitialiseSystemTab);
            app.AttemptsEditFieldLabel.Text = 'Attempts';
            app.AttemptsEditField = uieditfield(app.InitialiseSystemTab, 'numeric');
            app.AttemptsEditField.Limits = [0 Inf];
            app.AttemptsEditField.Value = 3;
            app.CreateNewSystemButton = uibutton(app.UIFigure, 'push');
            app.CreateNewSystemButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewSystemButtonPushed, true);
            app.CreateNewSystemButton.Text = 'CreateNewSystem';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end