classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HelloGeezerLabel               matlab.ui.control.Label
        LogoutButton                   matlab.ui.control.Button
        SystemTabs                     matlab.ui.container.TabGroup
        SystemManagerTab               matlab.ui.container.Tab
        AbortStartupButton             matlab.ui.control.Button
        SystemwillarminEditField       matlab.ui.control.NumericEditField
        SystemwillarminEditFieldLabel  matlab.ui.control.Label
        TestSystemButton               matlab.ui.control.Button
        ArmSystemSwitch                matlab.ui.control.Switch
        ArmSystemSwitchLabel           matlab.ui.control.Label
        SensorManagerTab               matlab.ui.container.Tab
        ConfirmActionButton_3          matlab.ui.control.Button
        LogicSwitch_2                  matlab.ui.control.Switch
        TypeSwitch_2                   matlab.ui.control.Switch
        PinNumberEditField_3           matlab.ui.control.NumericEditField
        PinNumberEditField_3Label      matlab.ui.control.Label
        NameEditField_3                matlab.ui.control.EditField
        NameEditField_3Label           matlab.ui.control.Label
        AddRemoveSwitch                matlab.ui.control.Switch
        LockManagerTab                 matlab.ui.container.Tab
        NameEditField_4                matlab.ui.control.EditField
        NameEditField_4Label           matlab.ui.control.Label
        ConfirmActionButton_4          matlab.ui.control.Button
        PinNumberEditField_4           matlab.ui.control.NumericEditField
        PinNumberLabel                 matlab.ui.control.Label
        AddRemoveSwitch_5              matlab.ui.control.Switch
        UserManagerTab                 matlab.ui.container.Tab
        ConfirmActionButton_5          matlab.ui.control.Button
        NameEditField_5                matlab.ui.control.EditField
        NameEditField_5Label           matlab.ui.control.Label
        AddRemoveSwitch_6              matlab.ui.control.Switch
        UserAdminSwitch                matlab.ui.control.Switch
        SensorStatesLabel              matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        CheckLoginButton               matlab.ui.control.Button
        LoginEditField                 matlab.ui.control.EditField
        LoginEditFieldLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        arduino
        abort
    end
    
    methods (Access = private)
        function value = CheckPassword(app)
            %{
            if (app.LoginEditField.Value == "admin")
                value = 1;
            else if(app.LoginEditField.Value == "user")
                    value = 2;
                else
                    value = 0;
                end
            end
            %}

            %code for serial communicator stuff
            message=readline(app.arduino);  %see what state the app.arduino is in, is neccessary to know when logging in, logging out, and if armed/disarmed
            if (message == "Check Login")
                password=app.LoginEditField.Value;
                username="Mumin";     %TEMPORARY VALUE UNTIL FACIAL REC DONE
                writeline(app.arduino,"1"); %if there is a login write 1 to the app.arduino
                app.communicate("Check Username",username) %then communicate to the app.arduino the username and password (NOTE: the username step will be changed when i add facial fully)
                app.communicate("Check Password",password)
    
                message=readline(app.arduino);
                if (message=="User Logged in") %need to add something like activating in 30 seconds, and then activate
                    value = 1;
                elseif (message=="Admin Logged in") %Admin pannel
                        value = 2;
                else
                    value = 0;
                
                end
            else
                disp("invalid app.arduino State") % FOR DEBUGGING
            end
        end
        
        function ArmSystem(app)
            app.SystemwillarminEditField.Visible = "on";
            app.AbortStartupButton.Visible = "on";
            %add while loop for the countdown
            countdown = 30;
            app.abort = 0;
            while (countdown < 0 && app.abort == 0)
                pause(1);
                countdown = countdown - 1;
                app.SystemwillarminEditField.Value = countdown;
            end
            writeline(app.arduino,"A");
            app.SystemwillarminEditField.Visible = "off";
            app.AbortStartupButton.Visible = "off";
            if (app.abort == 0)
                %make everything invisible as the system has logged out if
                %it hasnt aborted 
                app.SystemTabs.Visible = "off";
                app.LogoutButton.Visible = "off";
                app.CheckLoginButton.Visible = "on";
                app.LoginEditField.Visible = "on";
                app.LoginEditFieldLabel.Visible = "on";
            end 
        end

        function TestSystem(app)
            writeline(app.arduino,"T");

        end
        
        function Logout(app)
            app.SystemTabs.Visible = "off";
            app.LogoutButton.Visible = "off";
            app.CheckLoginButton.Visible = "on";
            app.LoginEditField.Visible = "on";
            app.LoginEditFieldLabel.Visible = "on";
            writeline(app.arduino,"L");
            disp('Logout');
        end
        
        function communicate(app,prompt,info)   %communicates w the app.arduino in a way that avoids errors
            while(1)
                if (readline(app.arduino)==prompt)
                    writeline(app.arduino,info);
                    break
                end
            end
        end

        function UpdateCredential(app)
            writeline(app.arduino,"A");
            AorR= app.AddRemoveSwitch_6.Value;
            %CONVERT TO A or R
            if (AorR == "Add")
                AorR = "A";
            else
                AorR = "R";
            end
            %Communication sequence with arduino to send correct info
            app.communicate("Add or Remove",AorR);
            username = app.NameEditField_5.Value;
            app.communicate("Get Username",username); %communicates the neccessary information to identify what is being selected
            if AorR=="A"
                isAdmin = app.UserAdminSwitch.Value;
                %CONVERT TO 1 or 0
                if (isAdmin == "Admin")
                    isAdmin = 1;
                else
                    isAdmin = 0;
                end
                app.communicate("Get Admin",isAdmin);
            end
            disp(readline(app.arduino));    %display success/error message to user for adding/removing, after the app.arduino has done the computation
        end
        
        function AddOrRemoveSensor(app)
            writeline(app.arduino,"D");
            AorR= app.AddRemoveSwitch.Value;
            %CONVERT TO A or R
            if (AorR == "Add")
                AorR = "A";
                %communicate info to matlab
                app.communicate("Add or Remove",AorR);
                app.communicate("Sensor or Lock","Sensor");
                app.communicate("Pin Number",app.PinNumberEditField_3.Value);
                app.communicate("Name",app.NameEditField_3.Value);
                app.communicate("Logic",app.LogicSwitch_2.Value);
                app.communicate("Standard or Pullup",app.TypeSwitch_2.Value);
            else
                AorR = "R";
                %communicate info to matlab
                app.communicate("Add or Remove",AorR);
                app.communicate("Sensor or Lock","Sensor");
                app.communicate("Sensor Number",app.PinNumberEditField_3.Value);
            end
        end
        
        function AddOrRemoveLock(app)
            writeline(app.arduino,"D");
            AorR= app.AddRemoveSwitch_5.Value;
            %CONVERT TO A or R
            if (AorR == "Add")
                AorR = "A";
                %communicate info to matlab
                app.communicate("Add or Remove",AorR);
                app.communicate("Sensor or Lock","Lock");
                app.communicate("Pin Number",app.PinNumberEditField_4.Value);
                app.communicate("Name",app.NameEditField_4.Value);
            else
                AorR = "R";
                %communicate info to matlab
                app.communicate("Add or Remove",AorR);
                app.communicate("Sensor or Lock","Lock");
                app.communicate("Lock Number",app.PinNumberEditField_3.Value);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SystemTabs.Visible = "off";
            app.LogoutButton.Visible = "off";
            clear app.arduino;
            app.arduino = serialport("COM8",9600);
            configureTerminator(app.arduino,"CR/LF");
        end

        % Button pushed function: CheckLoginButton
        function CheckLoginButtonPushed(app, event)
            app.CheckLoginButton.Enable = "off";
            app.LoginEditField.Enable = "off";
            value = app.CheckPassword();
            if (value == 2) %put check if login correct here
                app.SystemTabs.Visible = "on";
                app.SensorManagerTab.Parent = app.SystemTabs; %this code ensures tabs are visible when needed 
                app.LockManagerTab.Parent = app.SystemTabs;
                app.UserManagerTab.Parent = app.SystemTSabs;
                app.LogoutButton.Visible = "on";
                app.CheckLoginButton.Visible = "off";
                app.LoginEditField.Visible = "off";
                app.LoginEditFieldLabel.Visible = "off";
            elseif(value == 1)
                app.SystemTabs.Visible = "on";
                app.SensorManagerTab.Parent = [];   %this code effectively hides the tabs as there is not currently a visibility toggle per tabs
                app.LockManagerTab.Parent = [];
                app.UserManagerTab.Parent = [];
                app.LogoutButton.Visible = "on";
                app.CheckLoginButton.Visible = "off";
                app.LoginEditField.Visible = "off";
                app.LoginEditFieldLabel.Visible = "off";
                app.ArmSystemSwitch.Value = "on";   %flick the switch back 
            else
                disp('awww');
            end 
            app.LoginEditField.Value = '';
            app.CheckLoginButton.Enable = "on";
            app.LoginEditField.Enable = "on";
        end

        % Button pushed function: TestSystemButton
        function TestSystemButtonPushed(app, event)
            app.TestSystem();
        end

        % Value changed function: ArmSystemSwitch
        function ArmSystemSwitchValueChanged(app, event)
            value = app.ArmSystemSwitch.Value;
            if (value == "On")
                app.ArmSystem();
            end
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            app.Logout();
        end

        % Value changed function: AddRemoveSwitch
        function AddRemoveSwitchValueChanged(app, event)
            value = app.AddRemoveSwitch.Value;
            if (value == "Add")
                app.LogicSwitch_2.Visible = "on";
                app.TypeSwitch_2.Visible = "on";
                app.PinNumberEditField_3.Visible = "on";
                app.NameEditField_3.Visible = "on";
            else
                app.LogicSwitch_2.Visible = "off";
                app.TypeSwitch_2.Visible = "off";
                app.PinNumberEditField_3.Visible = "on";
                app.NameEditField_3.Visible = "on";
            end
        end

        % Button pushed function: AbortStartupButton
        function AbortStartupButtonPushed(app, event)
            app.abort = 1;
        end

        % Value changed function: AddRemoveSwitch_6
        function AddRemoveSwitch_6ValueChanged(app, event)
            value = app.AddRemoveSwitch_6.Value;
            % if the value is Add then make name  and user admin switch
            % visible, if the value is Remove than make only the name
            % visible
            if (value == "Add")
                app.UserAdminSwitch.Visible = "on";
            else
                app.UserAdminSwitch.Visible = "off";
            end

        end

        % Button pushed function: ConfirmActionButton_5
        function ConfirmActionButton_5Pushed(app, event)
            app.UpdateCredential();
        end

        % Button pushed function: ConfirmActionButton_3
        function ConfirmActionButton_3Pushed(app, event)
            app.AddOrRemoveSensor();
        end

        % Button pushed function: ConfirmActionButton_4
        function ConfirmActionButton_4Pushed(app, event)
            app.AddOrRemoveLock();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoginEditFieldLabel = uilabel(app.UIFigure);
            app.LoginEditFieldLabel.Text = 'Login';
            app.LoginEditField = uieditfield(app.UIFigure, 'text');
            app.CheckLoginButton = uibutton(app.UIFigure, 'push');
            app.CheckLoginButton.ButtonPushedFcn = createCallbackFcn(app, @CheckLoginButtonPushed, true);
            app.CheckLoginButton.Text = 'Check Login';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Sensor'; 'State'};
            app.UITable.RowName = {};
            app.SensorStatesLabel = uilabel(app.UIFigure);
            app.SensorStatesLabel.Text = 'Sensor States';
            app.SystemTabs = uitabgroup(app.UIFigure);
            app.SystemManagerTab = uitab(app.SystemTabs);
            app.SystemManagerTab.Title = 'System Manager';
            app.ArmSystemSwitchLabel = uilabel(app.SystemManagerTab);
            app.ArmSystemSwitchLabel.Text = 'Arm System';
            app.ArmSystemSwitch = uiswitch(app.SystemManagerTab, 'slider');
            app.ArmSystemSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmSystemSwitchValueChanged, true);
            app.TestSystemButton = uibutton(app.SystemManagerTab, 'push');
            app.TestSystemButton.ButtonPushedFcn = createCallbackFcn(app, @TestSystemButtonPushed, true);
            app.TestSystemButton.Text = 'Test System';
            app.SystemwillarminEditFieldLabel = uilabel(app.SystemManagerTab);
            app.SystemwillarminEditFieldLabel.Visible = 'off';
            app.SystemwillarminEditFieldLabel.Text = 'System will arm in';
            app.SystemwillarminEditField = uieditfield(app.SystemManagerTab, 'numeric');
            app.SystemwillarminEditField.Editable = 'off';
            app.SystemwillarminEditField.Visible = 'off';
            app.AbortStartupButton = uibutton(app.SystemManagerTab, 'push');
            app.AbortStartupButton.ButtonPushedFcn = createCallbackFcn(app, @AbortStartupButtonPushed, true);
            app.AbortStartupButton.Visible = 'off';
            app.AbortStartupButton.Text = 'Abort Startup';
            app.SensorManagerTab = uitab(app.SystemTabs);
            app.SensorManagerTab.Title = 'Sensor Manager';
            app.AddRemoveSwitch = uiswitch(app.SensorManagerTab, 'slider');
            app.AddRemoveSwitch.Items = {'Add', 'Remove'};
            app.AddRemoveSwitch.ValueChangedFcn = createCallbackFcn(app, @AddRemoveSwitchValueChanged, true);
            app.AddRemoveSwitch.Value = 'Add';
            app.NameEditField_3Label = uilabel(app.SensorManagerTab);
            app.NameEditField_3Label.Text = 'Name';
            app.NameEditField_3 = uieditfield(app.SensorManagerTab, 'text');
            app.PinNumberEditField_3Label = uilabel(app.SensorManagerTab);
            app.PinNumberEditField_3Label.Text = 'PinNumber';
            app.PinNumberEditField_3 = uieditfield(app.SensorManagerTab, 'numeric');
            app.TypeSwitch_2 = uiswitch(app.SensorManagerTab, 'slider');
            app.TypeSwitch_2.Items = {'Input', 'Input Pullup'};
            app.TypeSwitch_2.Value = 'Input';
            app.LogicSwitch_2 = uiswitch(app.SensorManagerTab, 'slider');
            app.LogicSwitch_2.Items = {'Positive Logic', 'Negative Logic'};
            app.LogicSwitch_2.Value = 'Positive Logic';
            app.ConfirmActionButton_3 = uibutton(app.SensorManagerTab, 'push');
            app.ConfirmActionButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConfirmActionButton_3Pushed, true);
            app.ConfirmActionButton_3.Text = 'Confirm Action';
            app.LockManagerTab = uitab(app.SystemTabs);
            app.LockManagerTab.Title = 'Lock Manager';
            app.AddRemoveSwitch_5 = uiswitch(app.LockManagerTab, 'slider');
            app.AddRemoveSwitch_5.Items = {'Add', 'Remove'};
            app.AddRemoveSwitch_5.Value = 'Add';
            app.PinNumberLabel = uilabel(app.LockManagerTab);
            app.PinNumberLabel.Text = 'PinNumber';
            app.PinNumberEditField_4 = uieditfield(app.LockManagerTab, 'numeric');
            app.ConfirmActionButton_4 = uibutton(app.LockManagerTab, 'push');
            app.ConfirmActionButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConfirmActionButton_4Pushed, true);
            app.ConfirmActionButton_4.Text = 'Confirm Action';
            app.NameEditField_4Label = uilabel(app.LockManagerTab);
            app.NameEditField_4Label.Text = 'Name';
            app.NameEditField_4 = uieditfield(app.LockManagerTab, 'text');
            app.UserManagerTab = uitab(app.SystemTabs);
            app.UserManagerTab.Title = 'User Manager';
            app.UserAdminSwitch = uiswitch(app.UserManagerTab, 'slider');
            app.UserAdminSwitch.Items = {'User', 'Admin'};
            app.UserAdminSwitch.Value = 'User';
            app.AddRemoveSwitch_6 = uiswitch(app.UserManagerTab, 'slider');
            app.AddRemoveSwitch_6.Items = {'Add', 'Remove'};
            app.AddRemoveSwitch_6.ValueChangedFcn = createCallbackFcn(app, @AddRemoveSwitch_6ValueChanged, true);
            app.AddRemoveSwitch_6.Value = 'Add';
            app.NameEditField_5Label = uilabel(app.UserManagerTab);
            app.NameEditField_5Label.Text = 'Name';
            app.NameEditField_5 = uieditfield(app.UserManagerTab, 'text');
            app.ConfirmActionButton_5 = uibutton(app.UserManagerTab, 'push');
            app.ConfirmActionButton_5.ButtonPushedFcn = createCallbackFcn(app, @ConfirmActionButton_5Pushed, true);
            app.ConfirmActionButton_5.Text = 'Confirm Action';
            app.LogoutButton = uibutton(app.UIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.HelloGeezerLabel = uilabel(app.UIFigure);
            app.HelloGeezerLabel.Text = 'Hello Geezer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end